classdef BVAMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExperimentButtonGroup         matlab.ui.container.ButtonGroup
        LCAButton                     matlab.ui.control.RadioButton
        TCAButton                     matlab.ui.control.RadioButton
        TumblingEButton               matlab.ui.control.RadioButton
        AccommodationButton           matlab.ui.control.RadioButton
        SubjectNumberEditFieldLabel   matlab.ui.control.Label
        SubjectNumberEditField        matlab.ui.control.NumericEditField
        NumberofRepetitionsEditFieldLabel  matlab.ui.control.Label
        NumberofRepetitionsEditField  matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        EyeDropDownLabel              matlab.ui.control.Label
        EyeDropDown                   matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        load('last_subject.mat')
        app.SubjectNumberEditField.Value=sn;    
        end

        % Selection changed function: ExperimentButtonGroup
        function ExperimentButtonGroupSelectionChanged(app, event)

            selectedButton = app.ExperimentButtonGroup.SelectedObject;
            %assignin('base', 'btn', selectedButton)
        end

        % Value changed function: SubjectNumberEditField
        function SubjectNumberEditFieldValueChanged(app, event)
            value = app.SubjectNumberEditField.Value;
            
        end

        % Value changed function: NumberofRepetitionsEditField
        function NumberofRepetitionsEditFieldValueChanged(app, event)
            value = app.NumberofRepetitionsEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            assignin('base', 'ex', app.ExperimentButtonGroup.SelectedObject.Text)
            assignin('base', 'sn', app.SubjectNumberEditField.Value)
            assignin('base', 'rn', app.NumberofRepetitionsEditField.Value)
            assignin('base', 'ey', app.EyeDropDown.Value)
            sn= app.SubjectNumberEditField.Value;
            save('last_subject', 'sn')

            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExperimentButtonGroup = uibuttongroup(app.UIFigure);
            app.ExperimentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExperimentButtonGroupSelectionChanged, true);
            app.ExperimentButtonGroup.Title = 'Experiment';
            app.LCAButton = uiradiobutton(app.ExperimentButtonGroup);
            app.LCAButton.Text = 'LCA';
            app.LCAButton.Value = true;
            app.TCAButton = uiradiobutton(app.ExperimentButtonGroup);
            app.TCAButton.Text = 'TCA';
            app.TumblingEButton = uiradiobutton(app.ExperimentButtonGroup);
            app.TumblingEButton.Text = 'Tumbling E';
            app.AccommodationButton = uiradiobutton(app.ExperimentButtonGroup);
            app.AccommodationButton.Text = 'Accommodation';
            app.SubjectNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectNumberEditFieldLabel.Text = 'Subject Number';
            app.SubjectNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubjectNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectNumberEditFieldValueChanged, true);
            app.NumberofRepetitionsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofRepetitionsEditFieldLabel.Text = 'Number of Repetitions';
            app.NumberofRepetitionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofRepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRepetitionsEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EyeDropDownLabel = uilabel(app.UIFigure);
            app.EyeDropDownLabel.Text = 'Eye';
            app.EyeDropDown = uidropdown(app.UIFigure);
            app.EyeDropDown.Items = {'Right', 'Left', 'Stereo'};
            app.EyeDropDown.Value = 'Right';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BVAMS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end